classdef imageComparator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxeDiagonal       matlab.ui.control.UIAxes
        UIAxeVertical       matlab.ui.control.UIAxes
        UIAxeHorizontal     matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
        SSIMEdit            matlab.ui.control.NumericEditField
        SSIMLabel           matlab.ui.control.Label
        MSEEdit             matlab.ui.control.NumericEditField
        MSELabel            matlab.ui.control.Label
        PSNREdit            matlab.ui.control.NumericEditField
        PSNREditFieldLabel  matlab.ui.control.Label
        Title               matlab.ui.control.Label
    end

    
    properties (Access = private)
        Img1 % Image 1
        Img2 % Image 2
        mymse  % MSE
        mypsnr % PSNR
        myssim % SSIM
    end
    
    methods (Access = private)
        
        function results = myMSE(app)
            results = immse(app.Img1,app.Img2);
        end
        
        function results = myPSNR(app)
            results = psnr(app.Img1,app.Img2);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img1, img2, title)
            app.Img1  = uint8(img1);
            app.Img2  = uint8(img2);
            if(~isempty(title))
                app.Title.Text = title;
            else
                app.Title.Text = 'Figure';
            end
            
            app.MSEEdit.Value =myMSE(app);
            imshow(app.Img1,'Parent',app.UIAxes);
            imshow(app.Img2,'Parent',app.UIAxes2);
            
            app.MSEEdit.Value  = immse(app.Img2,app.Img1);
            app.PSNREdit.Value = psnr(app.Img2,app.Img1);
            app.SSIMEdit.Value = ssim(app.Img2,app.Img1);
            %% Extraction des colonnes:
            [col_image_1,col_image_2] = vecteurColonne(app.Img2);
            
            %% Extraction des lignes:
            [lignes_image_1,lignes_image_2] = vecteurLigne(app.Img2);
            colors= [0 .447 .741;.85 .325 .098;.929 .694 .125];
            
            
            randIndex1 = randperm(numel(col_image_1));
            randIndex1 = randIndex1(1:3000);

            %% Traçage de la correlation horizontale:
            x =lignes_image_1(randIndex1);
            y =lignes_image_2(randIndex1);
            s1 = scatter(app.UIAxeHorizontal,x,y,'filled');
            xlabel(app.UIAxeHorizontal,'Pixel (x,y)')
            ylabel(app.UIAxeHorizontal,'Pixel (x+1,y)')
            s1.LineWidth = 0.6;
            s1.MarkerEdgeColor = colors(1,:);
            % s1.MarkerFaceColor = [0.4660 0.6740 0.1880];
            
            %% Traçage de la correlation verticale:
            
            
            x =col_image_1(randIndex1);
            y =col_image_2(randIndex1);
            s2 = scatter(app.UIAxeVertical,x,y,'filled');
            xlabel(app.UIAxeVertical,'Pixel (x,y)')
            ylabel(app.UIAxeVertical,'Pixel (x+1,y)')
            s2.LineWidth = 0.6;
            s2.MarkerEdgeColor = colors(2,:);
            % s2.MarkerFaceColor = [0.9290 0.6940 0.1250];
            
            
            %% Extraction des diagonales:
            [diag_image_1,diag_image_2] = diagonal(app.Img2);
            x =diag_image_1(randIndex1);
            y =diag_image_2(randIndex1);
            %% Traçage de la correlation diagonale:
             s3 = scatter(app.UIAxeDiagonal,x,y,'filled');
            xlabel(app.UIAxeDiagonal,'Pixel (x,y)')
            ylabel(app.UIAxeDiagonal,'Pixel (x+1,y)') 
             s3.LineWidth = 0.6;
             s3.MarkerEdgeColor = colors(3,:);
             % s3.MarkerFaceColor = [0 0.4470 0.7410];
        end

        % Callback function: UIAxes
        function UIAxesButtonDown(app, event)
            histogramApp(app.Img1);
        end

        % Callback function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            histogramApp(app.Img2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxeDiagonal = uiaxes(app.UIFigure);
            title(app.UIAxeDiagonal, 'Diagonal')
            xlabel(app.UIAxeDiagonal, '')
            ylabel(app.UIAxeDiagonal, '')
            zlabel(app.UIAxeDiagonal, '')
            app.UIAxeDiagonal.XTick = [0 100 200 300];
            app.UIAxeDiagonal.XTickLabel = {'0'; '100'; '200'; '300'};
            app.UIAxeDiagonal.YTick = [0 100 200 300];
            app.UIAxeDiagonal.YTickLabel = {'0'; '100'; '200'; '300'};
            app.UIAxeVertical = uiaxes(app.UIFigure);
            title(app.UIAxeVertical, 'Vertical')
            xlabel(app.UIAxeVertical, '')
            ylabel(app.UIAxeVertical, '')
            zlabel(app.UIAxeVertical, '')
            app.UIAxeVertical.XTick = [0 100 200 300];
            app.UIAxeVertical.XTickLabel = {'0'; '100'; '200'; '300'};
            app.UIAxeVertical.YTick = [0 100 200 300];
            app.UIAxeVertical.YTickLabel = {'0'; '100'; '200'; '300'};
            app.UIAxeHorizontal = uiaxes(app.UIFigure);
            title(app.UIAxeHorizontal, 'Horizontal')
            xlabel(app.UIAxeHorizontal, '')
            ylabel(app.UIAxeHorizontal, '')
            zlabel(app.UIAxeHorizontal, '')
            app.UIAxeHorizontal.XTick = [0 100 200 300];
            app.UIAxeHorizontal.XTickLabel = {'0'; '100'; '200'; '300'};
            app.UIAxeHorizontal.YTick = [0 100 200 300];
            app.UIAxeHorizontal.YTickLabel = {'0'; '100'; '200'; '300'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Image 2')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            zlabel(app.UIAxes2, '')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image 1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, '')
            app.SSIMEdit = uieditfield(app.UIFigure, 'numeric');
            app.SSIMLabel = uilabel(app.UIFigure);
            app.SSIMLabel.Text = 'SSIM';
            app.MSEEdit = uieditfield(app.UIFigure, 'numeric');
            app.MSELabel = uilabel(app.UIFigure);
            app.MSELabel.Text = 'MSE';
            app.PSNREdit = uieditfield(app.UIFigure, 'numeric');
            app.PSNREditFieldLabel = uilabel(app.UIFigure);
            app.PSNREditFieldLabel.Text = 'PSNR';
            app.Title = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = imageComparator(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end